classdef TestLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LogsTextArea                   matlab.ui.control.TextArea
        LogsTextAreaLabel              matlab.ui.control.Label
        DriveawayfromobjectButton      matlab.ui.control.Button
        ManualControlPanel             matlab.ui.container.Panel
        mmLabel                        matlab.ui.control.Label
        StepsizeSpinner                matlab.ui.control.Spinner
        StepsizeSpinnerLabel           matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        ManualYLeftBtn                 matlab.ui.control.Button
        manualYRightBtn                matlab.ui.control.Button
        HolddistancetoobjectCheckBox   matlab.ui.control.CheckBox
        DistanceinmmSpinner            matlab.ui.control.Spinner
        DistanceinmmSpinnerLabel       matlab.ui.control.Label
        CoordinateSystemDropDown       matlab.ui.control.DropDown
        CoordinateSystemDropDownLabel  matlab.ui.control.Label
        ManualZDownBtn                 matlab.ui.control.Button
        manualXRightBtn                matlab.ui.control.Button
        ManualXLeftBtn                 matlab.ui.control.Button
        ManualZUpBtn                   matlab.ui.control.Button
        EmergencystopButton            matlab.ui.control.Button
        AutomaticControlPanel          matlab.ui.container.Panel
        MicrowavescanningPanel         matlab.ui.container.Panel
        VNAPanel                       matlab.ui.container.Panel
        StartmicroscanButton           matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        DistancebetweenpointsSpinner   matlab.ui.control.Spinner
        DistancebetweenpointsSpinnerLabel  matlab.ui.control.Label
        NrofpointsSpinner              matlab.ui.control.Spinner
        NrofpointsSpinnerLabel         matlab.ui.control.Label
        ScanmodeButtonGroup            matlab.ui.container.ButtonGroup
        ScanselectionofpointsButton    matlab.ui.control.ToggleButton
        ScanareaButton                 matlab.ui.control.ToggleButton
        ScanwholeobjectButton          matlab.ui.control.ToggleButton
        DmodelreconstructionPanel      matlab.ui.container.Panel
        DatahandelingButtonGroup       matlab.ui.container.ButtonGroup
        ComplimentdataButton           matlab.ui.control.ToggleButton
        ReplacedataButton              matlab.ui.control.ToggleButton
        ScanningprotocolDropDown       matlab.ui.control.DropDown
        ScanningprotocolDropDownLabel  matlab.ui.control.Label
        ScanmodelButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)


        stepSize = 0;
        objPoints;
        yumiTCP;
        k;

        %coordinateSystem = app.CoordinateSystemDropDown.value;  % Description
        %Property2 % Description
    end
    
    methods (Access = private)
       
        function con = connectToRobotTCP(app)
            
        end
        
        function results = testIfStepAllowed(app, newCoord, objectCoord)
            [in, center] = inhull(newCoord, app.objPoints);
            %k = delaunay(objectCoord);
            %in = ~isnan(tsearchn(objectCoord, k, newCoord));
            results = ~in;
            %plot3(app.UIAxes,center(1), center(2), center(3),'.','MarkerSize',10, 'Color','Red');
            if in
                o = app.LogsTextArea.Value;
                t = now;
                d = string(datetime(t,'ConvertFrom','datenum'));
                test = string(app.LogsTextArea.Value);
                newText = d + ': Movement denied: New coordinate would be in object.';
                app.LogsTextArea.Value = vertcat(test, newText);
                %n = length(app.LogsTextArea.Value);
                %app.LogsTextArea.Value{n+1, 1} = text;
                %test = append(app.LogsTextArea.Value, newline, ' Movement denied: New coordinate would be in object.');
                %app.LogsTextArea.Value = test;
                %app.LogsTextArea.Value = join(test);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanmodelButton
        function ScanmodelButtonPushed(app, event)
            rng(0,'twister')


N=4000;
r = 6;
center = [0,0,0];
P = zeros(N,3) ;
x1 = 0.01;
x2 = 0.02;
for i=1:N
    x1 = rand;
    x2 = rand;
    Px = sqrt(x1*(2-x1))* cos(2*pi*x2);
    Py = sqrt(x1*(2-x1))* sin(2*pi*x2);
    Pz = 1-x1;
    Px = Px * r + center(1);
    Py = Py * r + center(2);
    Pz = Pz * r + center(3);

    P(i,:) = [Px, Py, Pz];
end
app.objPoints = P;
k = delaunay(P);
app.k = k;
hold(app.UIAxes,'on')
%plot3(app.UIAxes, P(:,1),P(:,2),P(:,3),'.','MarkerSize',10);
grid(app.UIAxes, 'on')
trisurf(k,P(:,1),P(:,2),P(:,3), 'FaceAlpha',0.5, 'LineStyle', 'none', 'Parent', app.UIAxes);
app.yumiTCP = plot3(app.UIAxes, 6,6,0,'.','MarkerSize',100, 'Color','Red');
%hold(app.UIAxes,'off')
        end

        % Value changed function: CoordinateSystemDropDown
        function CoordinateSystemDropDownValueChanged(app, event)
            app.coordinateSystem = app.CoordinateSystemDropDown.Value;
            
        end

        % Button pushed function: manualXRightBtn
        function manualXRightBtnPushed(app, event)
            %ylim = get(app.UIAxes, 'YLim');
            %xlim = get(app.UIAxes, 'XLim');
            %zlim = get(app.UIAxes, 'ZLim');
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.XData + app.stepSize;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData], app.objPoints);
                if allowed
                    app.yumiTCP.XData = newStep;
                    set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
                end

            end
            
           
        end

        % Button pushed function: ManualXLeftBtn
        function ManualXLeftBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.XData - app.stepSize;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData], app.objPoints);
                if allowed
                    app.yumiTCP.XData = newStep;
                    set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
                end

            end
        end

        % Button pushed function: ManualZUpBtn
        function ManualZUpBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.ZData + app.stepSize;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData], app.objPoints);
                if allowed
                    app.yumiTCP.ZData = newStep;
                    set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
                end

            end
        end

        % Button pushed function: ManualZDownBtn
        function ManualZDownBtnButtonPushed(app, event)
              if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.ZData - app.stepSize;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData], app.objPoints);
                if allowed
                    app.yumiTCP.ZData = newStep;
                    set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
                end
              end
        end

        % Button pushed function: manualYRightBtn
        function manualYRightBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.YData + app.stepSize;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData], app.objPoints);
                if allowed
                    app.yumiTCP.YData = newStep;
                    set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
                end

            end
        end

        % Button pushed function: ManualYLeftBtn
        function ManualYLeftBtnButtonPushed(app, event)
             if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.YData - app.stepSize;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData], app.objPoints);
                if allowed
                    app.yumiTCP.YData = newStep;
                    set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
                end

            end
        end

        % Value changed function: StepsizeSpinner
        function StepsizeSpinnerValueChanged(app, event)
            app.stepSize = app.StepsizeSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Object reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutomaticControlPanel = uipanel(app.UIFigure);
            app.AutomaticControlPanel.Title = 'Automatic Control';
            app.DmodelreconstructionPanel = uipanel(app.AutomaticControlPanel);
            app.DmodelreconstructionPanel.Title = '3D-model reconstruction';
            app.ScanmodelButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.ScanmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ScanmodelButtonPushed, true);
            app.ScanmodelButton.Text = 'Scan model';
            app.ScanningprotocolDropDownLabel = uilabel(app.DmodelreconstructionPanel);
            app.ScanningprotocolDropDownLabel.Text = 'Scanning protocol';
            app.ScanningprotocolDropDown = uidropdown(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup = uibuttongroup(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup.Title = 'Data handeling';
            app.ReplacedataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ReplacedataButton.Text = 'Replace data';
            app.ReplacedataButton.Value = true;
            app.ComplimentdataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ComplimentdataButton.Text = 'Compliment data';
            app.MicrowavescanningPanel = uipanel(app.AutomaticControlPanel);
            app.MicrowavescanningPanel.Title = 'Microwave scanning';
            app.ScanmodeButtonGroup = uibuttongroup(app.MicrowavescanningPanel);
            app.ScanmodeButtonGroup.Title = 'Scan mode';
            app.ScanwholeobjectButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanwholeobjectButton.Text = 'Scan whole object';
            app.ScanwholeobjectButton.Value = true;
            app.ScanareaButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanareaButton.Text = 'Scan area';
            app.ScanselectionofpointsButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanselectionofpointsButton.Text = 'Scan selection of points';
            app.NrofpointsSpinnerLabel = uilabel(app.MicrowavescanningPanel);
            app.NrofpointsSpinnerLabel.Text = 'Nr of points';
            app.NrofpointsSpinner = uispinner(app.MicrowavescanningPanel);
            app.DistancebetweenpointsSpinnerLabel = uilabel(app.MicrowavescanningPanel);
            app.DistancebetweenpointsSpinnerLabel.Text = 'Distance between points';
            app.DistancebetweenpointsSpinner = uispinner(app.MicrowavescanningPanel);
            app.UITable = uitable(app.MicrowavescanningPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.StartmicroscanButton = uibutton(app.MicrowavescanningPanel, 'push');
            app.StartmicroscanButton.Text = 'Start micro scan';
            app.VNAPanel = uipanel(app.MicrowavescanningPanel);
            app.VNAPanel.Title = 'VNA';
            app.EmergencystopButton = uibutton(app.UIFigure, 'push');
            app.EmergencystopButton.Text = 'Emergency stop';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.ManualZUpBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZUpBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZUpBtnButtonPushed, true);
            app.ManualZUpBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.ManualZUpBtn.Text = '';
            app.ManualXLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualXLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualXLeftBtnButtonPushed, true);
            app.ManualXLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualXLeftBtn.Text = '';
            app.manualXRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualXRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualXRightBtnPushed, true);
            app.manualXRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualXRightBtn.Text = '';
            app.ManualZDownBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZDownBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZDownBtnButtonPushed, true);
            app.ManualZDownBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualZDownBtn.Text = '';
            app.CoordinateSystemDropDownLabel = uilabel(app.ManualControlPanel);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.CoordinateSystemDropDown = uidropdown(app.ManualControlPanel);
            app.CoordinateSystemDropDown.Items = {'Carteasian', 'Cylindrical', 'Spherical'};
            app.CoordinateSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemDropDownValueChanged, true);
            app.CoordinateSystemDropDown.Value = 'Carteasian';
            app.DistanceinmmSpinnerLabel = uilabel(app.ManualControlPanel);
            app.DistanceinmmSpinnerLabel.Enable = 'off';
            app.DistanceinmmSpinnerLabel.Text = 'Distance in mm';
            app.DistanceinmmSpinner = uispinner(app.ManualControlPanel);
            app.DistanceinmmSpinner.Enable = 'off';
            app.HolddistancetoobjectCheckBox = uicheckbox(app.ManualControlPanel);
            app.HolddistancetoobjectCheckBox.Text = 'Hold distance to object';
            app.manualYRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualYRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualYRightBtnButtonPushed, true);
            app.manualYRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualYRightBtn.Text = '';
            app.ManualYLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualYLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualYLeftBtnButtonPushed, true);
            app.ManualYLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualYLeftBtn.Text = '';
            app.XLabel = uilabel(app.ManualControlPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.ManualControlPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.ManualControlPanel);
            app.ZLabel.Text = 'Z';
            app.StepsizeSpinnerLabel = uilabel(app.ManualControlPanel);
            app.StepsizeSpinnerLabel.Text = 'Step size';
            app.StepsizeSpinner = uispinner(app.ManualControlPanel);
            app.StepsizeSpinner.Step = 0.1;
            app.StepsizeSpinner.Limits = [0 10];
            app.StepsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsizeSpinnerValueChanged, true);
            app.mmLabel = uilabel(app.ManualControlPanel);
            app.mmLabel.Text = 'mm';
            app.DriveawayfromobjectButton = uibutton(app.UIFigure, 'push');
            app.DriveawayfromobjectButton.Text = 'Drive away from object';
            app.LogsTextAreaLabel = uilabel(app.UIFigure);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.UIFigure);
            app.LogsTextArea.Value = {' '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end